classdef new_comm_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label_status_2              matlab.ui.control.Label
        Label_status_1              matlab.ui.control.Label
        QAMModulationButton         matlab.ui.control.Button
        SSBModulationButton         matlab.ui.control.Button
        STOPsignal1Button           matlab.ui.control.Button
        STARTsignal1Button          matlab.ui.control.Button
        STOPsignal2Button           matlab.ui.control.Button
        VoiceSpectrumAnalyzerLabel  matlab.ui.control.Label
        STARTsignal2Button          matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        FrequencyDomainPlot_2       matlab.ui.control.UIAxes
        TimeDomainPlot_2            matlab.ui.control.UIAxes
        FrequencyDomainPlot         matlab.ui.control.UIAxes
        TimeDomainPlot_1            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Recorder1
        Recorder2
        Audio1
        Audio2 % Audio recorder objects
    end
    
    methods (Access = private)
        
     function processRecording(app)
    % Retrieve audio data

    Fs = 48000; % Sampling frequency

    if isa(app.Recorder1, 'audiorecorder') && ~isrecording(app.Recorder1)
        audioSignal1 = getaudiodata(app.Recorder1);
        app.Audio1 = audioSignal1; 
        
        % plotting code% 
         
        % Time-Domain Plot
        t1 = (0:length(audioSignal1)-1) / Fs;
        plot(app.TimeDomainPlot_1, t1, audioSignal1);
        xlabel(app.TimeDomainPlot_1, 'Time (s)');
        ylabel(app.TimeDomainPlot_1, 'Amplitude');
        grid(app.TimeDomainPlot_1, 'on');

        % Frequency-Domain Analysis
        N = length(audioSignal1); % Signal length
        fftSignal = fft(audioSignal1); % Perform FFT
        frequencies = (0:N-1)*(Fs/N); % Frequency vector
        fftSignal = fftSignal(1:N/2); % Positive frequencies only
        frequencies = frequencies(1:N/2);

        % Update Frequency Plot
        plot(app.FrequencyDomainPlot, frequencies, abs(fftSignal1));
        xlabel(app.FrequencyDomainPlot, 'Frequency (Hz)');
        ylabel(app.FrequencyDomainPlot, 'Amplitude');
        grid(app.FrequencyDomainPlot, 'on');

        % Enable Interactive Control
        zoom(app.FrequencyDomainPlot, 'on'); % Enable zooming
        pan(app.FrequencyDomainPlot, 'on');  % Enable panning
        app.Label_status_1.Text = 'Recording 1 Done!';

     end

        % Process Recorder2
    if isa(app.Recorder2, 'audiorecorder') && ~isrecording(app.Recorder2)
        audioSignal2 = getaudiodata(app.Recorder2);
        app.Audio2 = audioSignal2;

        % plotting code% 

        % Time-Domain Plot
        t2 = (0:length(audioSignal2)-1) / Fs;
        plot(app.TimeDomainPlot_2, t2, audioSignal2);
        xlabel(app.TimeDomainPlot_2, 'Time (s)');
        ylabel(app.TimeDomainPlot_2, 'Amplitude');
        grid(app.TimeDomainPlot_2, 'on');

        % Frequency-Domain Analysis
        N = length(audioSignal2); % Signal length
        fftSignal = fft(audioSignal2); % Perform FFT
        frequencies = (0:N-1)*(Fs/N); % Frequency vector
        fftSignal = fftSignal(1:N/2); % Positive frequencies only
        frequencies = frequencies(1:N/2);

        % Update Frequency Plot
        plot(app.FrequencyDomainPlot_2, frequencies, abs(fftSignal));
        xlabel(app.FrequencyDomainPlot_2, 'Frequency (Hz)');
        ylabel(app.FrequencyDomainPlot_2, 'Amplitude');

        grid(app.FrequencyDomainPlot_2, 'on');

        % Enable Interactive Control
        zoom(app.FrequencyDomainPlot_2, 'on'); % Enable zooming
        pan(app.FrequencyDomainPlot_2, 'on');  % Enable panning
        app.Label_status_2.Text = 'Recording 2 Done!';
    end

end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTsignal2Button
        function STARTsignal2ButtonPushed(app, event)
        Tmax = 5;
        Fs = 48000;
        app.Label_status_2.Text = 'Recording started...';
        % Create audio recorder object
        app.Recorder2 = audiorecorder(Fs, 16, 1); % 16-bit, mono
        recordblocking(app.Recorder2, Tmax); % Start and stop automatically after Tmax seconds

        % Retrieve and process audio data
        processRecording(app);
        end

        % Button pushed function: STOPsignal2Button
        function STOPsignal2ButtonPushed(app, event)
        if isrecording(app.Recorder2)
            stop(app.Recorder2); % Stop recording
        end
        % Process the recorded audio
        processRecording(app);
        end

        % Button pushed function: STARTsignal1Button
        function STARTsignal1ButtonPushed(app, event)
        Tmax = 5;
        Fs = 48000;
        app.Label_status_1.Text = 'Recording started...';
        % Create audio recorder object
        app.Recorder1 = audiorecorder(Fs, 16, 1); % 16-bit, mono
        recordblocking(app.Recorder1, Tmax); % Start and stop automatically after Tmax seconds

        % Retrieve and process audio data
        processRecording(app);
        end

        % Button pushed function: STOPsignal1Button
        function STOPsignal1ButtonPushed(app, event)
        if isrecording(app.Recorder1)
            stop(app.Recorder1); % Stop recording
        end
        % Process the recorded audio
        processRecording(app);
        end

        % Button pushed function: SSBModulationButton
        function SSBModulationButtonPushed(app, event)
        % Check if both signals are recorded
        if isempty(app.Audio1) || isempty(app.Audio2)
            uialert(app.UIFigure, 'Please record both signals before modulation.', 'Missing Signals');
            return;
        end

        original_fs = 48000;
        target_fs = 220000;
        fc = 100000;

        % Resample both signals
        audio1_resampled = resample(app.Audio1, target_fs, original_fs);
        audio2_resampled = resample(app.Audio2, target_fs, original_fs);

        % Time & frequency vectors
        t1 = (0:length(audio1_resampled)-1)/target_fs;
        t2 = (0:length(audio2_resampled)-1)/target_fs;
        f1 = linspace(-target_fs/2, target_fs/2, length(audio1_resampled));
        f2 = linspace(-target_fs/2, target_fs/2, length(audio2_resampled));

        % FFT of resampled signals
        audio1f = fftshift(fft(audio1_resampled)/length(audio1_resampled));
        audio2f = fftshift(fft(audio2_resampled)/length(audio2_resampled));

        % Modulation
        SLSB = ssbmod(audio1_resampled, fc, target_fs, 0);           % LSB
        SUSB = ssbmod(audio2_resampled, fc, target_fs, 0, 'upper');  % USB

        SLSBf = fftshift(fft(SLSB)/length(SLSB));
        SUSBf = fftshift(fft(SUSB)/length(SUSB));

        % Demodulation
        dLSB = ssbdemod(SLSB, fc, target_fs);
        dUSB = ssbdemod(SUSB, fc, target_fs);
    
        dLSBf = fftshift(fft(dLSB)/length(dLSB));
        dUSBf = fftshift(fft(dUSB)/length(dUSB));

        % === Plot Results ===
        figure('Name', 'SSB Modulated Signals');
        subplot(2,2,1); plot(t1, SLSB); title('LSB Signal 1 - Time');
        subplot(2,2,2); plot(f1, abs(SLSBf)); title('LSB Signal 1 - Frequency');
        subplot(2,2,3); plot(t2, SUSB); title('USB Signal 2 - Time');
        subplot(2,2,4); plot(f2, abs(SUSBf)); title('USB Signal 2 - Frequency');

        figure('Name', 'Demodulated Signals');
        subplot(2,2,1); plot(t1, dLSB); title('Demodulated LSB - Time');
        subplot(2,2,2); plot(f1, abs(dLSBf)); title('Demodulated LSB - Frequency');
        subplot(2,2,3); plot(t2, dUSB); title('Demodulated USB - Time');
        subplot(2,2,4); plot(f2, abs(dUSBf)); title('Demodulated USB - Frequency');

        end

        % Button pushed function: QAMModulationButton
        function QAMModulationButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.TimeDomainPlot_1 = uiaxes(app.Panel);
            title(app.TimeDomainPlot_1, 'M1(t) ')
            xlabel(app.TimeDomainPlot_1, 'Time (s)')
            ylabel(app.TimeDomainPlot_1, 'Amplitude')
            zlabel(app.TimeDomainPlot_1, 'Z')
            app.FrequencyDomainPlot = uiaxes(app.Panel);
            title(app.FrequencyDomainPlot, 'M1(f) ')
            xlabel(app.FrequencyDomainPlot, 'Frequancy (Hz)')
            zlabel(app.FrequencyDomainPlot, 'Z')
            app.TimeDomainPlot_2 = uiaxes(app.Panel);
            title(app.TimeDomainPlot_2, 'M2(t) ')
            xlabel(app.TimeDomainPlot_2, 'Time (s)')
            ylabel(app.TimeDomainPlot_2, 'Amplitude')
            zlabel(app.TimeDomainPlot_2, 'Z')
            app.FrequencyDomainPlot_2 = uiaxes(app.Panel);
            title(app.FrequencyDomainPlot_2, 'M2(f) ')
            xlabel(app.FrequencyDomainPlot_2, 'Frequancy (Hz)')
            zlabel(app.FrequencyDomainPlot_2, 'Z')
            app.STARTsignal2Button = uibutton(app.UIFigure, 'push');
            app.STARTsignal2Button.ButtonPushedFcn = createCallbackFcn(app, @STARTsignal2ButtonPushed, true);
            app.STARTsignal2Button.Text = 'START (signal 2)';
            app.VoiceSpectrumAnalyzerLabel = uilabel(app.UIFigure);
            app.VoiceSpectrumAnalyzerLabel.Text = 'Voice Spectrum Analyzer';
            app.STOPsignal2Button = uibutton(app.UIFigure, 'push');
            app.STOPsignal2Button.ButtonPushedFcn = createCallbackFcn(app, @STOPsignal2ButtonPushed, true);
            app.STOPsignal2Button.Text = 'STOP (signal 2)';
            app.STARTsignal1Button = uibutton(app.UIFigure, 'push');
            app.STARTsignal1Button.ButtonPushedFcn = createCallbackFcn(app, @STARTsignal1ButtonPushed, true);
            app.STARTsignal1Button.Text = 'START (signal 1)';
            app.STOPsignal1Button = uibutton(app.UIFigure, 'push');
            app.STOPsignal1Button.ButtonPushedFcn = createCallbackFcn(app, @STOPsignal1ButtonPushed, true);
            app.STOPsignal1Button.Text = 'STOP (signal 1)';
            app.SSBModulationButton = uibutton(app.UIFigure, 'push');
            app.SSBModulationButton.ButtonPushedFcn = createCallbackFcn(app, @SSBModulationButtonPushed, true);
            app.SSBModulationButton.Text = 'SSB Modulation';
            app.QAMModulationButton = uibutton(app.UIFigure, 'push');
            app.QAMModulationButton.ButtonPushedFcn = createCallbackFcn(app, @QAMModulationButtonPushed, true);
            app.QAMModulationButton.Text = 'QAM Modulation';
            app.Label_status_1 = uilabel(app.UIFigure);
            app.Label_status_1.Text = '';
            app.Label_status_2 = uilabel(app.UIFigure);
            app.Label_status_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_comm_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end